classdef trab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatfilterUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EditarTab                       matlab.ui.container.Tab
        AlgoritmoListBoxLabel           matlab.ui.control.Label
        AlgoritmoListBox                matlab.ui.control.ListBox
        MiniImagem                      matlab.ui.control.UIAxes
        AplicarButton                   matlab.ui.control.Button
        OpesPanel                       matlab.ui.container.Panel
        VoltarButton                    matlab.ui.control.Button
        LimparTudoButton                matlab.ui.control.Button
        AvanarButton                    matlab.ui.control.Button
        CarregarImagemTab               matlab.ui.container.Tab
        ImagemSelecionada               matlab.ui.control.UIAxes
        EncontrarButton                 matlab.ui.control.Button
        SelecioneoarquivodaimagemLabel  matlab.ui.control.Label
        ImagemAtual                     matlab.ui.control.UIAxes
    end

    %% LEMBRAR DE TENTAR FAZER O VOLTARRR FUNCIONARRR SOCORRO 
    properties (Access = private)
        imagemCarregada % Imagem inicial carregada
        
        imagemProcessada % Imagem que está sendo processada
        
        figureThreshold % Tela do threshold
        
        figurePassaBaixa % Tela do Passa Baixa
        
        figurePassaAlta % Tela do Passa Alta
        
        figureRoberts % Tela do Roberts
        
        figurePrewitt % Tela do Prewitt
        
        figureSobel % Tela do Sobel
        
        figureLog % Tela do Log
        
        figureCanny % Tela do Canny
        
        figureZerocross % Tela do Zerocross
        
        figureNoise % Tela dos Ruídos
        
        figureCount % Tela da contagem de objetos
        
        numeroImagem % Variável auxiliar para contagem das imagens salvas
        
        numeroAuxiliarImagem % Variável auxiliar para contagem das imagens salvas
        
        numeroContagem % Variável auxiliar para contagem dos arquivos salvos na contagem de objetos
        
        numeroHistograma % Variável auxiliar para contagem dos arquivos salvos no histograma
        
        imagemCount % Description
        
        numeroAjuste% Description
    end

    methods (Access = private)
    
        
        function dialogThreshold(app)
            
               function aplicarThreshold(~, ~, app, Value)
                    app.imagemProcessada = im2bw(app.imagemProcessada, Value);
                    imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                    salvarImagem(app);
                    close(app.figureThreshold);
               end
               
               app.figureThreshold = uifigure;
               app.figureThreshold.Position = [300 300 250 100]; 
               app.figureThreshold.Name = "Threshold";
              
               spn = uispinner(app.figureThreshold);
               spn.Limits = [0 1];
               spn.Value = 0.5;
               spn.Step = 0.01;
               spn.Position = [150 65 60 20];
               
               txt = uilabel(app.figureThreshold);
               txt.Text = 'Valor do Threshold';
               txt.Position = [40 65 120 20];
               
               btn = uibutton(app.figureThreshold, 'ButtonPushedFcn', @(btn,event) aplicarThreshold(btn, event, app, spn.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 25 80 20];
               
        end
    
         function dialogPassaBaixa(app)
            
               function aplicarPassaBaixa(~, ~, app, Value, Value2)
                   if strcmp(Value2, 'Média') == 1
                       x = fspecial('average',Value);
                       app.imagemProcessada = imfilter(app.imagemProcessada, x);
   
                   else
                       %Faz pela mediana
                       dimensao = [Value, Value];
                       app.imagemProcessada = medfilt2(app.imagemProcessada, dimensao);

                   end
                   
                   imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                   salvarImagem(app);
                   close(app.figurePassaBaixa);
                   
               end
               
               app.figurePassaBaixa = uifigure;
               app.figurePassaBaixa.Position = [300 300 250 120]; 
               app.figurePassaBaixa.Name = "Passa-Baixa";
               
               dropdown = uidropdown(app.figurePassaBaixa);
               dropdown.Items = {'Média', 'Mediana'};
               dropdown.Position = [110 85 100 20];
               
               txt = uilabel(app.figurePassaBaixa);
               txt.Text = 'Tipo';
               txt.Position = [80 85 120 20];
               
               spn = uispinner(app.figurePassaBaixa);
               spn.Limits = [1 100];
               spn.Value = 3;
               spn.Step = 1;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figurePassaBaixa);
               txt.Text = 'Tamanho da máscara';
               txt.Position = [20 50 120 20];
               
               btn = uibutton(app.figurePassaBaixa, 'ButtonPushedFcn', @(btn,event) aplicarPassaBaixa(btn, event, app, spn.Value, dropdown.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 15 80 20];
               
        end

        function dialogPassaAlta(app)
            
            function aplicarPassaAlta(~, ~, app, tamMascara, tipo, amplificacao)
                   if strcmp(tipo, 'Básico') == 1
                       x = fspecial('average',tamMascara);
                       imagemAuxiliar = imfilter(app.imagemProcessada, x);
                       app.imagemProcessada = app.imagemProcessada - imagemAuxiliar;
              
                   else
                       x = fspecial('average',tamMascara);
                       imagemAuxiliar = imfilter(app.imagemProcessada, x);
                       app.imagemProcessada = (amplificacao * app.imagemProcessada) - imagemAuxiliar;
       
                   end
                   
                   imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                   salvarImagem(app);
                   close(app.figurePassaAlta);
               end
               
               app.figurePassaAlta = uifigure;
               app.figurePassaAlta.Position = [300 300 250 150];   
               app.figurePassaAlta.Name = "Passa-Alta";
               
               dropdown = uidropdown(app.figurePassaAlta);
               dropdown.Items = {'Básico', 'Alto Reforço'};
               dropdown.Position = [110 115 100 20];
               
               txt = uilabel(app.figurePassaAlta);
               txt.Text = 'Tipo';
               txt.Position = [80 115 120 20];
               
               spn1 = uispinner(app.figurePassaAlta);
               spn1.Limits = [1 100];
               spn1.Value = 3;
               spn1.Step = 1;
               spn1.Position = [150 80 60 20];
               
               txt = uilabel(app.figurePassaAlta);
               txt.Text = 'Tamanho da máscara';
               txt.Position = [20 80 120 20];
               
               spn2 = uispinner(app.figurePassaAlta);
               spn2.Limits = [0 100];
               spn2.Value = 1;
               spn2.Step = 1;
               spn2.Position = [150 50 60 20];
               
               txt2 = uilabel(app.figurePassaAlta);
               txt2.Text = 'Fator de Amplificação';
               txt2.Position = [20 50 120 20];
               
               btn = uibutton(app.figurePassaAlta, 'ButtonPushedFcn', @(btn,event) aplicarPassaAlta(btn, event, app, spn1.Value, dropdown.Value, spn2.Value));
               btn.Text = 'Aplicar';
               btn.Position = [90 15 80 20];
               
        end
    
        function dialogRoberts(app)
            
               function aplicarRoberts(~, ~, app, Value, Value2, Value3)
                   if Value3 == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Roberts');
              
                   elseif strcmp(Value2, '45°') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Roberts', Value, 'vertical');
              
                   elseif strcmp(Value2, '135°') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Roberts', Value, 'horizontal');
            
                   else
                       app.imagemProcessada = edge(app.imagemProcessada, 'Roberts', Value, 'both');
            
                   end
                   
                   imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                   salvarImagem(app);
                   close(app.figureRoberts);
               end
               
               app.figureRoberts = uifigure;
               app.figureRoberts.Position = [300 300 250 120]; 
               app.figureRoberts.Name = "Roberts";
               
               dropdown = uidropdown(app.figureRoberts);
               dropdown.Items = {'45°', '135°', 'Ambos'};
               dropdown.Position = [150 85 60 20];
               
               txt = uilabel(app.figureRoberts);
               txt.Text = 'Ângulos das bordas';
               txt.Position = [28 90 120 20];
               
               txt2 = uilabel(app.figureRoberts);
               txt2.Text = 'com a horizontal';
               txt2.Position = [48 75 120 20];
               
               spn = uispinner(app.figureRoberts);
               spn.Limits = [0 1];
               spn.Value = 0;
               spn.Step = 0.01;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figureRoberts);
               txt.Text = 'Threshold';
               txt.Position = [80 50 120 20];
               
               checkbox = uicheckbox(app.figureRoberts);
               checkbox.Text = "Fazer pelo Default";
               checkbox.Position = [20 15 130 20];
               
               btn = uibutton(app.figureRoberts, 'ButtonPushedFcn', @(btn,event) aplicarRoberts(btn, event, app, spn.Value, dropdown.Value, checkbox.Value));
               btn.Text = 'Aplicar';
               btn.Position = [150 15 80 20];
               
        end
    
        function dialogPrewitt(app)
            
               function aplicarPrewitt(~, ~, app, Value, Value2, Value3)
                   if Value3 == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Prewitt');
 
                   elseif strcmp(Value2, 'Vertical') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Prewitt', Value, 'vertical');
         
                   elseif strcmp(Value2, 'Horizontal') == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Prewitt', Value, 'horizontal');
           
                   else
                       app.imagemProcessada = edge(app.imagemProcessada, 'Prewitt', Value, 'both');
     
                   end
                   
                   imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                   salvarImagem(app);
                   close(app.figurePrewitt);
                   
               end
               
               app.figurePrewitt = uifigure;
               app.figurePrewitt.Position = [300 300 250 120];    
               app.figurePrewitt.Name = "Prewitt";
               
               dropdown = uidropdown(app.figurePrewitt);
               dropdown.Items = {'Vertical', 'Horizontal', 'Ambos'};
               dropdown.Position = [130 85 80 20];
               
               txt = uilabel(app.figurePrewitt);
               txt.Text = 'Direção das bordas';
               txt.Position = [10 85 120 20];               
               spn = uispinner(app.figurePrewitt);
               spn.Limits = [0 1];
               spn.Value = 0;
               spn.Step = 0.01;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figurePrewitt);
               txt.Text = 'Threshold';
               txt.Position = [60 50 120 20];
               
               checkbox = uicheckbox(app.figurePrewitt);
               checkbox.Text = "Fazer pelo Default";
               checkbox.Position = [20 15 130 20];
               
               btn = uibutton(app.figurePrewitt, 'ButtonPushedFcn', @(btn,event) aplicarPrewitt(btn, event, app, spn.Value, dropdown.Value, checkbox.Value));
               btn.Text = 'Aplicar';
               btn.Position = [150 15 80 20];
               
        end
    
        function dialogSobel(app)
            
               function aplicarSobel(~, ~, app, Value, Value2, Value3)
                   if Value3 == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Sobel');
              
                   else
                       if strcmp(Value2, 'Vertical') == 1
                           app.imagemProcessada = edge(app.imagemProcessada, 'Sobel', Value, 'vertical');
               
                       elseif strcmp(Value2, 'Horizontal') == 1
                           app.imagemProcessada = edge(app.imagemProcessada, 'Sobel', Value, 'horizontal');
                  
                       else
                           app.imagemProcessada = edge(app.imagemProcessada, 'Sobel', Value, 'both');
               
                       end
                   end
                   
                   imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                   salvarImagem(app);
                   close(app.figureSobel);
                   
               end
               
               app.figureSobel = uifigure;
               app.figureSobel.Position = [300 300 250 120];  
               app.figureSobel.Name = "Sobel";
               
               dropdown = uidropdown(app.figureSobel);
               dropdown.Items = {'Vertical', 'Horizontal', 'Ambos'};
               dropdown.Position = [130 85 80 20];
               
               txt = uilabel(app.figureSobel);
               txt.Text = 'Direção das bordas';
               txt.Position = [10 85 120 20];               
               spn = uispinner(app.figureSobel);
               spn.Limits = [0 1];
               spn.Value = 0;
               spn.Step = 0.01;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figureSobel);
               txt.Text = 'Threshold';
               txt.Position = [60 50 120 20];
               
               checkbox = uicheckbox(app.figureSobel);
               checkbox.Text = "Fazer pelo Default";
               checkbox.Position = [20 15 130 20];
               
               btn = uibutton(app.figureSobel, 'ButtonPushedFcn', @(btn,event) aplicarSobel(btn, event, app, spn.Value, dropdown.Value, checkbox.Value));
               btn.Text = 'Aplicar';
               btn.Position = [150 15 80 20];
               
        end
    
        function dialogLog(app)
            
               function aplicarLog(~, ~, app, Value, Value2, Value3)
                   if Value3 == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'log');
                   
                   else
                       app.imagemProcessada = edge(app.imagemProcessada, 'log', Value2, Value);
              
                   end
                   imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                   salvarImagem(app);
                   close(app.figureLog);
                   
               end
               
               app.figureLog = uifigure;
               app.figureLog.Position = [300 300 250 120];    
               app.figureLog.Name = "Log";
               
               spn1 = uispinner(app.figureLog);
               spn1.Limits = [0.1 100];
               spn1.Value = 2;
               spn1.Step = 0.5;
               spn1.Position = [150 85 60 20];
               
               txt = uilabel(app.figureLog);
               txt.Text = 'Sigma';
               txt.Position = [80 85 120 20];               
               
               spn2 = uispinner(app.figureLog);
               spn2.Limits = [0 1];
               spn2.Value = 0;
               spn2.Step = 0.001;
               spn2.Position = [150 50 60 20];
               
               txt = uilabel(app.figureLog);
               txt.Text = 'Threshold';
               txt.Position = [60 50 120 20];
               
               checkbox = uicheckbox(app.figureLog);
               checkbox.Text = "Fazer pelo Default";
               checkbox.Position = [20 15 130 20];
               
               btn = uibutton(app.figureLog, 'ButtonPushedFcn', @(btn,event) aplicarLog(btn, event, app, spn1.Value, spn2.Value, checkbox.Value));
               btn.Text = 'Aplicar';
               btn.Position = [150 15 80 20];
               
        end
    
        function dialogCanny(app)
            
            function aplicarCanny(~, ~, app, Value, Value2, Value3)
                   if Value3 == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'Canny');
               
                   else
                       app.imagemProcessada = edge(app.imagemProcessada, 'Canny', Value2, Value);
      
                   end
                   
                   imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                   salvarImagem(app);
                   close(app.figureCanny);
            end
               
               app.figureCanny = uifigure;
               app.figureCanny.Position = [300 300 250 120];    
               app.figureCanny.Name = "Canny";
               
               spn1 = uispinner(app.figureCanny);
               spn1.Limits = [0.1 100];
               spn1.Value = 2;
               spn1.Step = 0.5;
               spn1.Position = [150 85 60 20];
               
               txt = uilabel(app.figureCanny);
               txt.Text = 'Sigma';
               txt.Position = [80 85 120 20];               
               
               spn2 = uispinner(app.figureCanny);
               spn2.Limits = [0 1];
               spn2.Value = 0;
               spn2.Step = 0.001;
               spn2.Position = [150 50 60 20];
               
               txt = uilabel(app.figureCanny);
               txt.Text = 'Threshold';
               txt.Position = [60 50 120 20];
               
               checkbox = uicheckbox(app.figureCanny);
               checkbox.Text = "Fazer pelo Default";
               checkbox.Position = [20 15 130 20];
               
               btn = uibutton(app.figureCanny, 'ButtonPushedFcn', @(btn,event) aplicarCanny(btn, event, app, spn1.Value, spn2.Value, checkbox.Value));
               btn.Text = 'Aplicar';
               btn.Position = [150 15 80 20];
               
        end

        function dialogZerocross(app)
            
            function aplicarZerocross(~, ~, app, Value, Value2, Value3)
                   if Value3 == 1
                       app.imagemProcessada = edge(app.imagemProcessada, 'zerocross');
 
                   elseif strcmp(Value2, 'Average') == 1
                       h = fspecial('average');
                       app.imagemProcessada = edge(app.imagemProcessada, 'zerocross', Value, h);
         
                   elseif strcmp(Value2, 'Disk') == 1
                       h = fspecial('disk');
                       app.imagemProcessada = edge(app.imagemProcessada, 'zerocross', Value, h);
           
                   elseif strcmp(Value2, 'Gaussian') == 1
                       h = fspecial('gaussian');
                       app.imagemProcessada = edge(app.imagemProcessada, 'zerocross', Value, h);
     
                   elseif strcmp(Value2, 'Laplacian') == 1
                       h = fspecial('laplacian');
                       app.imagemProcessada = edge(app.imagemProcessada, 'zerocross', Value, h);

                   end
                   
                   imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                   salvarImagem(app);
                   close(app.figureZerocross);
                   
               end
               
               app.figureZerocross = uifigure;
               app.figureZerocross.Position = [300 300 250 120];    
               app.figureZerocross.Name = "Zerocross";
               
               dropdown = uidropdown(app.figureZerocross);
               dropdown.Items = {'Average', 'Disk', 'Gaussian', 'Laplacian'};
               dropdown.Position = [130 85 80 20];
               
               txt = uilabel(app.figureZerocross);
               txt.Text = 'Filtro';
               txt.Position = [88 85 120 20];    
               
               spn = uispinner(app.figureZerocross);
               spn.Limits = [0 1];
               spn.Value = 0;
               spn.Step = 0.01;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figureZerocross);
               txt.Text = 'Threshold';
               txt.Position = [60 50 120 20];
               
               checkbox = uicheckbox(app.figureZerocross);
               checkbox.Text = "Fazer pelo Default";
               checkbox.Position = [20 15 130 20];
               
               btn = uibutton(app.figureZerocross, 'ButtonPushedFcn', @(btn,event) aplicarZerocross(btn, event, app, spn.Value, dropdown.Value, checkbox.Value));
               btn.Text = 'Aplicar';
               btn.Position = [150 15 80 20];
               
        end    
    
        function dialogNoise(app)
            
            function aplicarNoise(~, ~, app, parametroAdicional, tipo, checkbox)
                   if checkbox == 1
                       if strcmp(tipo, 'Gaussiano') == 1
                            app.imagemProcessada = imnoise(app.imagemProcessada, 'gaussian');
          
                       elseif strcmp(tipo, 'Poisson') == 1
                            app.imagemProcessada = imnoise(app.imagemProcessada, 'poisson');
                            
                       elseif strcmp(tipo, 'Salt & Pepper') == 1 
                            app.imagemProcessada = imnoise(app.imagemProcessada, 'salt & pepper'); 
                       else
                            app.imagemProcessada = imnoise(app.imagemProcessada, 'speckle');
                       end
                       salvarImagem(app);
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       close(app.figureNoise);
                   elseif checkbox == 0
                       if strcmp(tipo, 'Gaussiano') == 1
                            app.imagemProcessada = imnoise(app.imagemProcessada, 'gaussian', parametroAdicional);
          
                       elseif strcmp(tipo, 'Poisson') == 1
                            app.imagemProcessada = imnoise(app.imagemProcessada, 'poisson');
                            
                       elseif strcmp(tipo, 'Salt & Pepper') == 1 
                            app.imagemProcessada = imnoise(app.imagemProcessada, 'salt & pepper', parametroAdicional); 
                       else
                            app.imagemProcessada = imnoise(app.imagemProcessada, 'speckle', parametroAdicional);
                       end
                       salvarImagem(app);
                       imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                       close(app.figureNoise);
                   end
               end
               
               app.figureNoise = uifigure;
               app.figureNoise.Position = [300 300 250 120];    
               app.figureNoise.Name = "Ruído";
               
               dropdown = uidropdown(app.figureNoise);
               dropdown.Items = {'Gaussiano', 'Poisson', 'Salt & Pepper', 'Speckle'};
               dropdown.Position = [150 85 80 20];
               
               txt = uilabel(app.figureNoise);
               txt.Text = 'Tipo de Ruído';
               txt.Position = [20 85 120 20];   
               
               spn = uispinner(app.figureNoise);
               spn.Limits = [0 1];
               spn.Value = 0;
               spn.Step = 0.001;
               spn.Position = [150 50 60 20];
               
               txt = uilabel(app.figureNoise);
               txt.Text = 'Parâmetro Adicional';
               txt.Position = [20 50 120 20];
               
               checkbox = uicheckbox(app.figureNoise);
               checkbox.Text = "Fazer pelo Default";
               checkbox.Position = [20 15 130 20];
               
               btn = uibutton(app.figureNoise, 'ButtonPushedFcn', @(btn,event) aplicarNoise(btn, event, app, spn.Value, dropdown.Value, checkbox.Value));
               btn.Text = 'Aplicar';
               btn.Position = [150 15 80 20];
               
        end
        
        function dialogCount(app)
            
               function aplicarCount(app)
                    if isempty(app.imagemCount) == 0
                        app.imagemProcessada = app.imagemCount;
                    end
                    
                    app.imagemProcessada = imfill(app.imagemProcessada, 'holes');

                    app.imagemProcessada = bwareaopen(app.imagemProcessada,15);
                                                                               
                    [B] = bwboundaries(app.imagemProcessada,'noholes');

                    imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                   
                    hold(app.ImagemAtual, 'on')
                    for k = 1:length(B)
                        boundary = B{k};
                        plot(app.ImagemAtual, boundary(:,2),boundary(:,1),'blue','LineWidth',2)
                    end
                    
                    text(app.ImagemAtual, 10,10,strcat('\color{green}Número de Objetos Encontrados:',num2str(length(B))), 'FontSize', 20)
                    title(app.ImagemAtual, strcat('Número de Objetos Encontrados:', num2str(length(B))));
                    salvarImagem(app);
                    salvarUiAxis(app);
                                        
               end
           
               function visualizarThreshold(~, ~, app, Value)
                    [linhas, colunas, numeroDeCanaisDeCor] = size(app.imagemProcessada);
                    if numeroDeCanaisDeCor > 1
                        app.imagemProcessada = rgb2gray(app.imagemProcessada);
                    end
                    app.imagemCount = imbinarize(app.imagemProcessada, Value);
                    imshow(app.imagemCount, 'Parent', app.ImagemAtual);
               end               
               
               if islogical(app.imagemProcessada) == false
                   
                   app.figureCount = uifigure;
                   app.figureCount.Position = [300 300 250 100]; 
                   app.figureCount.Name = "Threshold";
                  
                   spn = uispinner(app.figureCount);
                   spn.Limits = [0 1];
                   spn.Value = 0.5;
                   spn.Step = 0.01;
                   spn.Position = [150 55 60 20];
                   
                   txt = uilabel(app.figureCount);
                   txt.Text = 'Valor do Threshold';
                   txt.Position = [40 55 120 20];
                   
                   btn = uibutton(app.figureCount, 'ButtonPushedFcn', @(btn,event) aplicarCount(app));
                   btn.Text = 'Aplicar';
                   btn.Position = [140 15 80 20];
                   
                   btn2 = uibutton(app.figureCount, 'ButtonPushedFcn', @(btn2,event) visualizarThreshold(btn2, event, app, spn.Value));
                   btn2.Text = 'Visualizar';
                   btn2.Position = [30 15 80 20];
                   
               else
                   aplicarCount(app);
               end
               
        end
    
        function getImagemAtual(app)                    
                a = num2str(app.numeroAuxiliarImagem);
                if strcmp(a, '0') == 0
                    string = "imagemSalva" + a + ".png";
                    app.imagemProcessada = imread(convertStringsToChars(string));
                else
                    app.imagemProcessada = imread('imagemSalva.png');
                end
        end
        
        function salvarImagem(app)
            a = num2str(app.numeroImagem);
            string = "imagemSalva" + a + ".png";
            imwrite(app.imagemProcessada, convertStringsToChars(string));
            app.numeroAuxiliarImagem = app.numeroImagem;
            app.numeroImagem = app.numeroImagem + 1;
        end
    
        function salvarUiAxis(app)
            a = num2str(app.numeroContagem);
            string = "contagem" + a + ".png";
            fig1 = figure;
            ax = axes;
            copyobj(app.ImagemAtual.Children,ax);
            saveas(fig1, convertStringsToChars(string));
            app.numeroContagem = app.numeroContagem + 1;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.ImagemSelecionada, 'XTick', []);
            set(app.ImagemSelecionada, 'YTick', []);
            set(app.ImagemAtual, 'XTick', []);
            set(app.ImagemAtual, 'YTick', []);
            set(app.MiniImagem, 'XTick', []);
            set(app.MiniImagem, 'YTick', []);
            app.numeroImagem = 1;
            app.numeroAuxiliarImagem = 1;
            app.numeroContagem = 1;
            app.numeroHistograma = 1;
            app.numeroAjuste = 1;
        end

        % Button pushed function: EncontrarButton
        function EncontrarButtonPushed(app, event)
            [filename,user_canceled] = imgetfile();
            figure(app.MatfilterUIFigure);
            if user_canceled == false
                app.ImagemAtual.Color = 'white';
                app.imagemCarregada = imread(filename);
                app.imagemProcessada = app.imagemCarregada;
                imshow(app.imagemCarregada, 'Parent', app.ImagemSelecionada);  
                imshow(app.imagemCarregada, 'Parent', app.MiniImagem);
                cla(app.ImagemAtual);
                imwrite(app.imagemCarregada, convertStringsToChars("imagemSalva.png"));
            end                 
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            value = app.AlgoritmoListBox.Value;
            if strcmp(value, 'Escala de Cinza/Grayscale') == 1
                app.imagemProcessada = rgb2gray(app.imagemProcessada);
                imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                salvarImagem(app);
            elseif strcmp(value, 'Limiarização/Threshold') == 1
                dialogThreshold(app);
            elseif strcmp(value, 'Passa-Baixa') == 1
                dialogPassaBaixa(app);
            elseif strcmp(value, 'Passa-Alta') == 1
                dialogPassaAlta(app);
            elseif strcmp(value, 'Roberts') == 1
                dialogRoberts(app);
            elseif strcmp(value, 'Prewitt') == 1
                dialogPrewitt(app);
            elseif strcmp(value, 'Sobel') == 1
                dialogSobel(app);
            elseif strcmp(value, 'Log') == 1
                dialogLog(app);
            elseif strcmp(value, 'Zerocross') == 1
                dialogZerocross(app);
            elseif strcmp(value, 'Canny') == 1
                dialogCanny(app);
            elseif strcmp(value, 'Ruídos') == 1
                dialogNoise(app);
            elseif strcmp(value, 'Watershed') == 1
                [linhas, colunas, numeroDeCanaisDeCor] = size(app.imagemProcessada);
                if numeroDeCanaisDeCor > 1
                    app.imagemProcessada = rgb2gray(app.imagemProcessada);
                end
                if islogical(app.imagemCarregada) == 0
                    I = app.imagemProcessada;
                    app.imagemProcessada = imtophat(app.imagemProcessada, strel('disk', 10));
                    app.imagemProcessada = imadjust(app.imagemProcessada);
                    nivel = graythresh(app.imagemProcessada);
                    app.imagemProcessada = im2bw(app.imagemProcessada, nivel);
                end
                
                L = watershed(app.imagemProcessada);
                Lrgb = label2rgb(L);
                
                imagem2 = ~bwareaopen(~app.imagemProcessada, 10);
                D = -bwdist(~app.imagemProcessada);
                imagemWatershed = watershed(D);
                
                imagem2 = app.imagemProcessada;
                imagem2(imagemWatershed == 0) = 0;
                
                mask = imextendedmin(D,2);
                
                D2 = imimposemin(D,mask);
                imagemWatershed2 = watershed(D2);
                imagem3 = app.imagemProcessada;
                imagem3(imagemWatershed2 == 0) = 0;
                boundaries = bwboundaries(imagem3);
                imshow(imagem3, 'Parent', app.ImagemAtual);
                
                [B] = bwboundaries(imagem3,'noholes');
                hold(app.ImagemAtual, 'on')
                
                for k=1:length(B)
                   b = boundaries{k};
                   plot(app.ImagemAtual, b(:,2),b(:,1),'g','LineWidth',3);
                end

                %imshow(imagem3 == 0);

                
                %app.imagemProcessada = ~bwareaopen(~app.imagemProcessada, 10);
                %app.imagemProcessada = ~app.imagemProcessada;
                %distancia = -bwdist(app.imagemProcessada);
                %imshow(distancia);
                %distancia(app.imagemProcessada) = -Inf;
                %L = watershed(distancia);
                %app.imagemProcessada = label2rgb(L);
                %I(L==0)=0;
                %imshow(app.imagemProcessada);
                %imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
                
            elseif strcmp(value, 'Contagem de Objetos') == 1
                dialogCount(app);
            elseif strcmp(value, 'Histograma') == 1
                figure
                subplot(1,2,1)
                imshow(app.imagemProcessada);
                subplot(1,2,2)
                imhist(app.imagemProcessada);
                saveas(gcf,sprintf('histograma%d.png',app.numeroHistograma));
                app.numeroHistograma = app.numeroHistograma + 1;
            elseif strcmp(value, 'Ajuste Adaptativo de Histograma')
                app.imagemProcessada = adapthisteq(app.imagemProcessada);
                figure
                subplot(1,2,1)
                imshow(app.imagemProcessada);
                subplot(1,2,2)
                imhist(app.imagemProcessada);
                saveas(gcf,sprintf('ajustehistograma%d.png',app.numeroAjuste));
                app.numeroAjuste = app.numeroAjuste + 1;
            end
        end

        % Button pushed function: LimparTudoButton
        function LimparTudoButtonPushed(app, event)
            app.imagemProcessada = app.imagemCarregada;
            imshow(app.imagemCarregada, 'Parent', app.ImagemAtual);
        end

        % Button pushed function: VoltarButton
        function VoltarButtonPushed(app, event)
            if app.numeroAuxiliarImagem >= 1
                app.numeroAuxiliarImagem = app.numeroAuxiliarImagem - 1;
                getImagemAtual(app);
                imshow(app.imagemProcessada, 'Parent', app.ImagemAtual); 
            else
                app.imagemProcessada = app.imagemCarregada;
                imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
            end
            
        end

        % Button pushed function: AvanarButton
        function AvanarButtonPushed(app, event)
            if app.numeroAuxiliarImagem < app.numeroImagem - 1
                app.numeroAuxiliarImagem = app.numeroAuxiliarImagem + 1;
                getImagemAtual(app);
                imshow(app.imagemProcessada, 'Parent', app.ImagemAtual);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatfilterUIFigure = uifigure;
            app.MatfilterUIFigure.Name = 'Matfilter';
            app.TabGroup = uitabgroup(app.MatfilterUIFigure);
            app.EditarTab = uitab(app.TabGroup);
            app.EditarTab.Title = 'Editar';
            app.AlgoritmoListBoxLabel = uilabel(app.EditarTab);
            app.AlgoritmoListBoxLabel.Text = 'Algoritmo';
            app.AlgoritmoListBox = uilistbox(app.EditarTab);
            app.AlgoritmoListBox.Items = {'Limiarização/Threshold', 'Escala de Cinza/Grayscale', 'Passa-Alta', 'Passa-Baixa', 'Roberts', 'Prewitt', 'Sobel', 'Log', 'Zerocross', 'Canny', 'Ruídos', 'Watershed', 'Contagem de Objetos', 'Histograma', 'Ajuste Adaptativo de Histograma'};
            app.AlgoritmoListBox.Value = 'Limiarização/Threshold';
            app.MiniImagem = uiaxes(app.EditarTab);
            app.AplicarButton = uibutton(app.EditarTab, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.OpesPanel = uipanel(app.EditarTab);
            app.OpesPanel.Title = 'Opções';
            app.VoltarButton = uibutton(app.OpesPanel, 'push');
            app.VoltarButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarButtonPushed, true);
            app.VoltarButton.Text = 'Voltar';
            app.LimparTudoButton = uibutton(app.OpesPanel, 'push');
            app.LimparTudoButton.ButtonPushedFcn = createCallbackFcn(app, @LimparTudoButtonPushed, true);
            app.LimparTudoButton.Text = 'Limpar Tudo';
            app.AvanarButton = uibutton(app.OpesPanel, 'push');
            app.AvanarButton.ButtonPushedFcn = createCallbackFcn(app, @AvanarButtonPushed, true);
            app.AvanarButton.Text = 'Avançar';
            app.CarregarImagemTab = uitab(app.TabGroup);
            app.CarregarImagemTab.Title = 'Carregar Imagem';
            app.ImagemSelecionada = uiaxes(app.CarregarImagemTab);
            app.EncontrarButton = uibutton(app.CarregarImagemTab, 'push');
            app.EncontrarButton.ButtonPushedFcn = createCallbackFcn(app, @EncontrarButtonPushed, true);
            app.EncontrarButton.Text = 'Encontrar';
            app.SelecioneoarquivodaimagemLabel = uilabel(app.CarregarImagemTab);
            app.SelecioneoarquivodaimagemLabel.Text = 'Selecione o arquivo da imagem';
            app.ImagemAtual = uiaxes(app.MatfilterUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = trab1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatfilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatfilterUIFigure)
        end
    end
end